classdef BursiyerSecmeSistemi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BursiyerSecmeKararSistemiUIFigure  matlab.ui.Figure
        isitma                          matlab.ui.container.ButtonGroup
        soba                            matlab.ui.control.RadioButton
        kalorifer                       matlab.ui.control.RadioButton
        ArsaButtonGroup_2               matlab.ui.container.ButtonGroup
        Hayirarsa                       matlab.ui.control.RadioButton
        Evetarsa                        matlab.ui.control.RadioButton
        Yabancdil5ButtonGroup_2         matlab.ui.container.ButtonGroup
        IkisideVefat                    matlab.ui.control.RadioButton
        annevefat                       matlab.ui.control.RadioButton
        babavefat                       matlab.ui.control.RadioButton
        sanatBransOdul6ButtonGroup_3    matlab.ui.container.ButtonGroup
        AsgariucretussuButton           matlab.ui.control.RadioButton
        AsgariucretButton               matlab.ui.control.RadioButton
        Asgariucrettenaz                matlab.ui.control.RadioButton
        sanatBransOdul6ButtonGroup_2    matlab.ui.container.ButtonGroup
        BabaEmekli                      matlab.ui.control.RadioButton
        BabaCalismiyor                  matlab.ui.control.RadioButton
        babaCalisiyor                   matlab.ui.control.RadioButton
        OgrenciBelgesiSecme             matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        OgrTelefonNumaraEditField       matlab.ui.control.EditField
        TelefonNumaraLabel              matlab.ui.control.Label
        OgrTCEditField                  matlab.ui.control.EditField
        TCLabel                         matlab.ui.control.Label
        ResminiziSecinizButton          matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        OgrAdSoyadEditField             matlab.ui.control.EditField
        AdSoyadEditFieldLabel           matlab.ui.control.Label
        KaydetveGrntleButton            matlab.ui.control.Button
        DisiplinButtonGroup             matlab.ui.container.ButtonGroup
        Yok                             matlab.ui.control.RadioButton
        Var                             matlab.ui.control.RadioButton
        ailefertButtonGroup             matlab.ui.container.ButtonGroup
        dortveuzeriaile                 matlab.ui.control.RadioButton
        ucaile                          matlab.ui.control.RadioButton
        ikiaile                         matlab.ui.control.RadioButton
        biraile                         matlab.ui.control.RadioButton
        egitimsurecButtonGroup          matlab.ui.container.ButtonGroup
        Uzaktan                         matlab.ui.control.RadioButton
        Orgun                           matlab.ui.control.RadioButton
        edevletButtonGroup              matlab.ui.container.ButtonGroup
        Hayir                           matlab.ui.control.RadioButton
        Eveet                           matlab.ui.control.RadioButton
        ogrenciokuyanYOButtonGroup      matlab.ui.container.ButtonGroup
        DORTVEUZERI                     matlab.ui.control.RadioButton
        UC                              matlab.ui.control.RadioButton
        IKI                             matlab.ui.control.RadioButton
        BIR                             matlab.ui.control.RadioButton
        BursuAlmaButtonGroup            matlab.ui.container.ButtonGroup
        Hayirburs                       matlab.ui.control.RadioButton
        Evetburs                        matlab.ui.control.RadioButton
        ProgramiButtonGroup             matlab.ui.container.ButtonGroup
        YuksekLisans                    matlab.ui.control.RadioButton
        Lisans                          matlab.ui.control.RadioButton
        OnLisans                        matlab.ui.control.RadioButton
        sanatBransOdul6ButtonGroup      matlab.ui.container.ButtonGroup
        AnneEmekli                      matlab.ui.control.RadioButton
        AnneCalismiyor                  matlab.ui.control.RadioButton
        annecalisiyor                   matlab.ui.control.RadioButton
        Yabancdil5ButtonGroup           matlab.ui.container.ButtonGroup
        AnneBabaAyrButton               matlab.ui.control.RadioButton
        AnneBababirlikteyayorButton     matlab.ui.control.RadioButton
        bransBasari4ButtonGroup         matlab.ui.container.ButtonGroup
        DigerProgram                    matlab.ui.control.RadioButton
        VakifProgrami                   matlab.ui.control.RadioButton
        DevletuniversitesiprogramButton  matlab.ui.control.RadioButton
        ogrenciCalismaButtonGroup       matlab.ui.container.ButtonGroup
        CalismiyorButton                matlab.ui.control.RadioButton
        GunlukgeilirlibiristecalisiyorumButton  matlab.ui.control.RadioButton
        AylikMaas                       matlab.ui.control.RadioButton
        Calismiyor                      matlab.ui.control.RadioButton
        Aileningelirdurumu3ButtonGroup  matlab.ui.container.ButtonGroup
        KiradaButton_2                  matlab.ui.control.RadioButton
        KendiEvindeButton               matlab.ui.control.RadioButton
        Diplomabasarpuani2ButtonGroup   matlab.ui.container.ButtonGroup
        DevletyurdundaButton            matlab.ui.control.RadioButton
        OzelyurtdaButton                matlab.ui.control.RadioButton
        KiradaButton                    matlab.ui.control.RadioButton
        AileyanindaButton               matlab.ui.control.RadioButton
        BursiyerSemeKararDestekSistemiLabel  matlab.ui.control.Label
        CinsiyetinizNedir1ButtonGroup   matlab.ui.container.ButtonGroup
        YabancUyrukluButton             matlab.ui.control.RadioButton
        TurkiyeCumhuriyetiVatandasiButton  matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        ogrenciler{}
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Selection changed function: CinsiyetinizNedir1ButtonGroup
        function CinsiyetinizNedir1ButtonGroupSelectionChanged(app, event)
         
        end

        % Button pushed function: ResminiziSecinizButton
        function ResminiziSecinizButtonPushed(app, event)
     % Resim seçimini kontrol et
[filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp;*.gif', 'Resim Dosyaları (*.jpg, *.png, *.bmp, *.gif)'; '*.*', 'Tüm Dosyalar (*.*)'}, 'Resim Seç');

% Kullanıcı resim seçerse devam et
if ~isequal(filename, 0)
    % Dosya yolu ve adını birleştir
    resimYolu = fullfile(pathname, filename);

    % Bir miktar bekleme ekle
    pause(0.1);

    % Seçilen resmi görüntü bileşenine ata
    app.Image.ImageSource = resimYolu;

    % Görüntüyü güncelle ve pencereyi ön plana al
    drawnow;
    figure(app.BursiyerSecmeKararSistemiUIFigure);
else
    % Kullanıcı resim seçmezse mesaj göster
    msgbox('Resim seçilmedi.', 'Bilgi', 'modal');
end

        end

        % Button pushed function: KaydetveGrntleButton
        function KaydetveGrntleButtonPushed(app, event)

            puan = 0;
             %VERİTABANINI BAGLAMA
            conn=database('BURSIYERSECMEKARARDESTEK','','');
            

ogrenci = struct(...
    'AdSoyad', app.OgrAdSoyadEditField.Value, ...
    'OgrTC', app.OgrTCEditField.Value, ...
    'OgrTelefonnNumarasi', app.OgrTelefonNumaraEditField.Value, ...
    'OgrBelge', app.Image2.ImageSource, ...
    'OgrFoto', app.Image.ImageSource, ...
    'Vatandaslik', 0, ...
    'OgrYasam', 0, 'AileYasam', 0, ...
    'AnneCalismaDurum', 0, 'BabaCalismaDurum', 0, 'AileGelirDurum', 0, ...
    'OgrOkulu', 0, 'BirliktelikDurumu', 0, 'YasamDurumu', 0, 'Edevlet', 0, ...
    'AileFert', 0, 'YOokuyanFert', 0, 'Programi', 0, 'EgitimSurec', 0, ...
    'BursAlmaDurumu', 0, 'DisiplinCeza', 0, 'OgrenciCalisma', '', ...
    'GayrimenkulDurumu', 0, 'IsitmaDurumu', 0, 'ToplamPuan', 0);

OgrenciBilgi = struct(...
    'AdSoyad', app.OgrAdSoyadEditField.Value, ...
    'OgrTC', app.OgrTCEditField.Value, ...
    'OgrTelefonnNumarasi', app.OgrTelefonNumaraEditField.Value, ...
    'OgrFoto', app.Image.ImageSource, ...
    'OgrBelge', app.Image2.ImageSource, ...
    'Vatandaslik', '', ...
    'OgrYasam', '', 'AileYasam', '', ...
    'AnneCalismaDurum', '', 'BabaCalismaDurum', '', 'AileGelirDurum', '', ...
    'OgrOkulu', '', 'BirliktelikDurumu', '', 'YasamDurumu', '', 'Edevlet', '', ...
    'AileFert', '', 'YOokuyanFert', '', 'Programi', '', 'EgitimSurec', '', ...
    'BursAlmaDurumu', '', 'DisiplinCeza', '', 'OgrenciCalisma', '', ...
    'GayrimenkulDurumu', '', 'IsitmaDurumu', '', 'ToplamPuan', 0);


             %ogrenci Adsoyad
            if(app.OgrAdSoyadEditField.Value=="")
                msgbox('Ogrenci AdSoyad girişi yapmalısınız!','Hata','error','modal');
                return;
            end

            %ogrenci TC
            if(app.OgrTCEditField.Value=="")
                msgbox('Ogrenci TC girişi yapmalısınız!','Hata','error','modal');
                return;
            end

           
            %ogrenci Tel No
            if(app.OgrTelefonNumaraEditField.Value=="")
                msgbox('Telefon numarası girişi yapmalısınız!','Hata','error','modal');
                return;
            end


            %Vatandaslik secımı
            if(app.TurkiyeCumhuriyetiVatandasiButton.Value)
                komut="select [TcUyrukluPuan] from [dbo].[Tbl_PUANN] where ID=1";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.Vatandaslik=data;
                OgrenciBilgi.Vatandaslik=app.TurkiyeCumhuriyetiVatandasiButton.Text;
            elseif(app.YabancUyrukluButton.Value)
                komut="SELECT [YabanciUyrukluPuan] FROM [dbo].[Tbl_PUANN] WHERE ID = 1 ";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.Vatandaslik=data;
                OgrenciBilgi.Vatandaslik=app.YabancUyrukluButton.Text;
            else
                 msgbox('Vatandaslık seçimi yapmalısınız.', 'Hata', 'error', 'modal');
                 return;
            end

           %Ogrencının Ikemtegah durumu
            if(app.AileyanindaButton.Value)
                komut="select [AileYanindaPuan] from [dbo].[Tbl_PUANN] where ID=1";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.OgrYasam=data;
                OgrenciBilgi.OgrYasam=app.AileyanindaButton.Text;
            elseif(app.KiradaButton.Value)
                komut="select [KiradaPuan] from [dbo].[Tbl_PUANN] where ID=1";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.OgrYasam=data;
                OgrenciBilgi.OgrYasam=app.KiradaButton.Text;
            elseif(app.OzelyurtdaButton.Value)
               komut="select [OzelYurtPuan] from [dbo].[Tbl_PUANN] where ID=1";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.OgrYasam=data;
                OgrenciBilgi.OgrYasam=app.OzelyurtdaButton.Text;
            elseif(app.DevletyurdundaButton.Value)
               komut="select [DevletYurtPuan] from [dbo].[Tbl_PUANN] where ID=1";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.OgrYasam=data;
                OgrenciBilgi.OgrYasam=app.DevletyurdundaButton.Text;

            else 
                msgbox('ogrencı ıkemtgahı puanını seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
            end

            %Ailenin ikemetgahı durumu
            if(app.KendiEvindeButton.Value)
                komut="select [KendiEvPuan] from [dbo].[Tbl_PUANN] where ID=1";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.AileYasam=data;
                OgrenciBilgi.AileYasam=app.KendiEvindeButton.Text;
            elseif(app.KiradaButton_2.Value)
                komut="select [KiraEvPuan] from [dbo].[Tbl_PUANN] where ID=1";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.AileYasam=data;
                OgrenciBilgi.AileYasam=app.KiradaButton_2.Text;
           else 
                msgbox('Aile ikemetgahı Durumunuzu seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
            end 

            %Anne calısma durumu
            if(app.annecalisiyor.Value)
                komut="select [AnneCalisiyorPuan] from [dbo].[Tbl_PUANN] where ID=1";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.AnneCalismaDurum=data;
                OgrenciBilgi.AnneCalismaDurum=app.annecalisiyor.Text;
            elseif(app.AnneCalismiyor.Value)
                komut="select [AnneCalismiyorPuan] from [dbo].[Tbl_PUANN] where ID=1";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.AnneCalismaDurum=data;
                OgrenciBilgi.AnneCalismaDurum=app.AnneCalismiyor.Text;
            elseif(app.AnneEmekli.Value)
                komut="select [AnneEmekliPuan] from [dbo].[Tbl_PUANN] where ID=1";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.AnneCalismaDurum=data;
                OgrenciBilgi.AnneCalismaDurum=app.AnneEmekli.Text;
            else 
                msgbox('Anne calısma durumunu seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
            end 

            %Baba Calışma
            if(app.babaCalisiyor.Value)
                komut="select [BabaCalisiyorPuan] from [dbo].[Tbl_PUANN] where ID=1";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.BabaCalismaDurum=data;
                OgrenciBilgi.BabaCalismaDurum=app.babaCalisiyor.Text;
            elseif(app.BabaCalismiyor.Value)
                komut="select [BabaCalismiyorPuan] from [dbo].[Tbl_PUANN] where ID=1";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.BabaCalismaDurum=data;
                OgrenciBilgi.BabaCalismaDurum=app.BabaCalismiyor.Text;
            elseif(app.BabaEmekli.Value)
                komut="select [BabaEmekliPuan] from [dbo].[Tbl_PUANN] where ID=1";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.BabaCalismaDurum=data;
                OgrenciBilgi.BabaCalismaDurum=app.BabaEmekli.Text;
            else 
                msgbox('Baba calısma durumunu seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
            end 

            %Aile aylık geliri
            if(app.Asgariucrettenaz.Value)
                komut="select [AsgariUcrettenAzPuan] from [dbo].[Tbl_PUANN] where ID=1";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.AileGelirDurum=data;
                OgrenciBilgi.AileGelirDurum=app.Asgariucrettenaz.Text;
            elseif(app.AsgariucretButton.Value)
                komut="select [AsgariUcretPuan] from [dbo].[Tbl_PUANN] where ID=1";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.AileGelirDurum=data;
                OgrenciBilgi.AileGelirDurum=app.AsgariucretButton.Text;
            elseif(app.AsgariucretussuButton.Value)
                komut="select [AsgariUcretUssuPuan] from [dbo].[Tbl_PUANN] where ID=1";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.AileGelirDurum=data;
                OgrenciBilgi.AileGelirDurum=app.AsgariucretussuButton.Text;
            else 
                msgbox('Aile aylık geliri seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
            end 

            %Okulun statusu
            if(app.DevletuniversitesiprogramButton.Value)
                komut="select [DevletUniPuan] from [dbo].[Tbl_PUANN] where ID=1";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.OgrOkulu=data;
                OgrenciBilgi.OgrOkulu=app.DevletuniversitesiprogramButton.Text;
            elseif(app.VakifProgrami.Value)
                komut="select [VakifUniPuan] from [dbo].[Tbl_PUANN] where ID=1";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.OgrOkulu=data;
                OgrenciBilgi.OgrOkulu=app.VakifProgrami.Text;
            elseif(app.DigerProgram.Value)
                komut="select [DigerUniPuan] from [dbo].[Tbl_PUANN] where ID=1";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.OgrOkulu=data;
                OgrenciBilgi.OgrOkulu=app.DigerProgram.Text;
            else 
                msgbox('Okulun statusunu seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
            end 

   
           %BirliktelikDurumu
           if(app.AnneBababirlikteyayorButton.Value)
               komut="select [AnneBabaBirliktePuan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.BirliktelikDurumu=data; 
               OgrenciBilgi.BirliktelikDurumu=app.AnneBababirlikteyayorButton.Text;
           elseif(app.AnneBabaAyrButton.Value)
               komut="select [AnneBabaAyriPuan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.BirliktelikDurumu=data; 
               OgrenciBilgi.BirliktelikDurumu=app.AnneBabaAyrButton.Text;
           else
               msgbox('Birliktelik durumunu seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
           end 


           %YasamDurumu
           if(app.babavefat.Value)
               komut="select [BabaVefatPuan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.YasamDurumu=data;
               OgrenciBilgi.YasamDurumu=app.babavefat.Text;
           elseif(app.annevefat.Value)
               komut="select [AnneVefatPuan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.YasamDurumu=data;
               OgrenciBilgi.YasamDurumu=app.annevefat.Text;
           elseif(app.IkisideVefat.Value)
              komut="select [IkisideVefatPuan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.YasamDurumu=data;
               OgrenciBilgi.YasamDurumu=app.IkisideVefat.Text;
           else
                msgbox('Yasam durumunu seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
           end


             %e devlet sorusu 
             if(app.Eveet.Value)
               komut="select [EDevletEvetPuan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.Edevlet=data;
               OgrenciBilgi.Edevlet=app.Eveet.Text;
             elseif(app.Hayir.Value)
               komut="select [EDevletHayirPuan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.Edevlet=data;
               OgrenciBilgi.Edevlet=app.Hayir.Text;
             else
                  msgbox('Edevlet sorusunu seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
             end

             %AileFert
               if(app.dortveuzeriaile.Value)
               komut="select [AileFert4veFazlasiPuan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.AileFert=data;
               OgrenciBilgi.AileFert=app.dortveuzeriaile.Text;
             elseif(app.ucaile.Value)
               komut="select [AileFert3Puan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.AileFert=data;
               OgrenciBilgi.AileFert=app.ucaile.Text;
            elseif(app.ikiaile.Value)
               komut="select [AileFert2Puan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.AileFert=data;
               OgrenciBilgi.AileFert=app.ikiaile.Text;
            elseif(app.biraile.Value)
               komut="select [AileFert1Puan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.AileFert=data;
               OgrenciBilgi.AileFert=app.biraile.Text;
             else
                  msgbox('Aile fert sayısını seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
               end


               %YO Okuyan ogrencifert
               if(app.DORTVEUZERI.Value)
               komut="select [YOokuyan4veFazlasiPuan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.YOokuyanFert=data;
               OgrenciBilgi.YOokuyanFert=app.DORTVEUZERI.Text;
             elseif(app.UC.Value)
               komut="select [YOokuyan3Puan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.YOokuyanFert=data;
               OgrenciBilgi.YOokuyanFert=app.UC.Text;
            elseif(app.IKI.Value)
               komut="select [YOokuyan2Puan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.YOokuyanFert=data;
               OgrenciBilgi.YOokuyanFert=app.IKI.Text;
            elseif(app.BIR.Value)
               komut="select [YOokuyan1Puan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.YOokuyanFert=data;
               OgrenciBilgi.YOokuyanFert=app.BIR.Text;
             else
                  msgbox('Yuksek ogretım okuyan fert sayısını seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
             end


           %Programı
           if(app.OnLisans.Value)
               komut="select [OnlisansPuan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.Programi=data;
               OgrenciBilgi.Programi=app.OnLisans.Text;
           elseif(app.Lisans.Value)
               komut="select [LisansPuan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.Programi=data;
               OgrenciBilgi.Programi=app.Lisans.Text;
           elseif(app.YuksekLisans.Value)
               komut="select [YuksekLisansPuan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.Programi=data;
               OgrenciBilgi.Programi=app.YuksekLisans.Text;
           else   
               msgbox('Programinizi seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
           end

           %EgitimSurec
             if(app.Orgun.Value)
               komut="select [OrgunEgitimPuan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.EgitimSurec=data;
               OgrenciBilgi.EgitimSurec=app.Orgun.Text;
             elseif(app.Uzaktan.Value)
               komut="select [UzaktanEgitimPuan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.EgitimSurec=data;
               OgrenciBilgi.EgitimSurec=app.Uzaktan.Text;
             else
                  msgbox('egitim surecinizi seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
             end

             %BursAlmaDurumu
             if(app.Evetburs.Value)
               komut="select [BursEvetPuan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.BursAlmaDurumu=data;
               OgrenciBilgi.BursAlmaDurumu=app.Evetburs.Text;
             elseif(app.Hayirburs.Value)
               komut="select [BursHayirPuan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.BursAlmaDurumu=data;
               OgrenciBilgi.BursAlmaDurumu=app.Hayirburs.Text;
             else
                  msgbox(' BursAlmaDurumu seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
             end

             %Disiplin ceza alma durumu
             if(app.Var.Value)
               komut="select [DisiplinVarPuan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.DisiplinCeza=data;
               OgrenciBilgi.DisiplinCeza=app.Var.Text;
             elseif(app.Yok.Value)
               komut="select [DisiplinYokPuan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.DisiplinCeza=data;
               OgrenciBilgi.DisiplinCeza=app.Yok.Text;
             else
                 msgbox('DisiplinCeza sorusunu seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
             end
             
             %OgrenciCalisma sorusu
             if(app.Calismiyor.Value)
               komut="select [OgrCalismiyorPuan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.OgrenciCalisma=data;
                OgrenciBilgi.OgrenciCalisma=app.Calismiyor.Text;
             elseif(app.AylikMaas.Value)
               komut="select [OgrAylikMaasPuan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.OgrenciCalisma=data;
               OgrenciBilgi.OgrenciCalisma=app.AylikMaas.Text;
             elseif(app.GunlukgeilirlibiristecalisiyorumButton.Value)
               komut="select [OgrGunlukGelirPuan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
               puan=puan+data;
               ogrenci.OgrenciCalisma=data;
               OgrenciBilgi.OgrenciCalisma=app.GunlukgeilirlibiristecalisiyorumButton.Text;
             elseif(app.CalismiyorButton.Value)
                 komut="select [OgrCalismiyorPuan] from [dbo].[Tbl_PUANN] where ID=1";
                 data=fetch(conn,komut);
                 data=str2num(char(table2cell(data)));
                 puan=puan+data;
                 ogrenci.OgrenciCalisma=data;
                 OgrenciBilgi.OgrenciCalisma=app.CalismiyorButton.Text;
             else
                  msgbox('OgrenciCalisma sorusunu seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
             end

             %arsa varmı
              if(app.Evetarsa.Value)
               komut="select [GayrimenkulEvetPuan] from [dbo].[Tbl_PUANN] where ID=1";
               data=fetch(conn,komut);
               data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.GayrimenkulDurumu=data;
                OgrenciBilgi.GayrimenkulDurumu=app.Evetarsa.Text;
             elseif(app.Hayirarsa.Value)
                komut="select [GayrimenkulHayirPuan] from [dbo].[Tbl_PUANN] where ID=1";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.GayrimenkulDurumu=data;
                OgrenciBilgi.GayrimenkulDurumu=app.Hayirarsa.Text;
                else
                  msgbox('arsa veya gayrimenkul sorusunu seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
              end

               %ısıtma sorusu
              if(app.soba.Value)
                komut="select [IsitmaSobaPuan] from [dbo].[Tbl_PUANN] where ID=1";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.IsitmaDurumu=data;
                OgrenciBilgi.IsitmaDurumu=app.soba.Text;
             elseif(app.kalorifer.Value)
                komut="select [IsitmaKaloriferPuan] from [dbo].[Tbl_PUANN] where ID=1";
                data=fetch(conn,komut);
                data=str2num(char(table2cell(data)));
                puan=puan+data;
                ogrenci.IsitmaDurumu=data;
                OgrenciBilgi.IsitmaDurumu=app.kalorifer.Text;
                else
                  msgbox('ısıtma sorusunu seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
             end


             %Resim secımı:
             if(app.Image.ImageSource == "")
               msgbox('Resim seçimi yapmalısınız.', 'Hata', 'error', 'modal');
               return;
             end
             %Ogrenci Belge secımı:
             if(app.Image2.ImageSource == "")
               msgbox('Ogrenci Belge seçimi yapmalısınız.', 'Hata', 'error', 'modal');
               return;
             end


             %kriterlerin önem agırlıgını içeren matris
             onemMatrisi = [
                 3; %Vatandaslik
                 5; %OgrYasam
                 4; %AileYasam
                 3; %AnneCalismaDurum
                 3; %BabaCalismaDurum
                 2; %AileGelirDurum
                 5; %OgrOkulu
                 2; %BirliktelikDurumu
                 4; %YasamDurumu
                 3; %Edevlet
                 3; %AileFert
                 4; %YOokuyanFert
                 2; %Programi
                 5; %EgitimSurec
                 5; %BursAlmaDurumu
                 5; %DisiplinCeza
                 5; %OgrenciCalisma
                 5; %GayrimenkulDurumu
                 5; %IsitmaDurumu

             ];

             %ogrencının secımlerını içeren vektor
             secimVektoru = [
                 ogrenci.Vatandaslik;
                 ogrenci.OgrYasam;
                 ogrenci.AileYasam;
                 ogrenci.AnneCalismaDurum;
                 ogrenci.BabaCalismaDurum;
                 ogrenci.AileGelirDurum;
                 ogrenci.OgrOkulu;
                 ogrenci.BirliktelikDurumu;
                 ogrenci.YasamDurumu;
                 ogrenci.Edevlet;
                 ogrenci.AileFert;
                 ogrenci.YOokuyanFert;
                 ogrenci.Programi;
                 ogrenci.EgitimSurec;
                 ogrenci.BursAlmaDurumu;
                 ogrenci.DisiplinCeza;
                 ogrenci.OgrenciCalisma;  
                 ogrenci.GayrimenkulDurumu;
                 ogrenci.IsitmaDurumu;
             ];
             
             %KARAR DESTEK ICIN PUAN HESAPLAMA
             puan=sum(onemMatrisi .* secimVektoru);


             %toplam puanı ogrencı struct ekle
             ogrenci.ToplamPuan=puan;
             OgrenciBilgi.ToplamPuan=puan;

             ogrenci.OgrFoto =app.Image.ImageSource;
             ogrenci.OgrBelge=app.Image2.ImageSource;

             %VERİTABANI İŞLEMLERİ
             conn=database('BURSIYERSECMEKARARDESTEK','','');
            komut="INSERT INTO [dbo].[Tbl_Ogrenci] ([AdSoyad],[OgrTC],[OgrFoto],[OgrBelge],[OgrTelefonnNumarasi],[Vatandaslik],[OgrYasam],[AileYasam],[AnneCalismaDurum],[BabaCalismaDurum],[AileGelirDurum],[OgrOkulu],[BirliktelikDurumu],[YasamDurumu],[Edevlet],[AileFert],[YOokuyanFert] ,[Programi],[EgitimSurec],[BursAlmaDurumu],[DisiplinCeza],[OgrenciCalisma],[GayrimenkulDurumu],[IsitmaDurumu],[ToplamPuan])  VALUES " + "('"+app.OgrAdSoyadEditField.Value+"' ,'"+app.OgrTCEditField.Value+"', '"+app.Image.ImageSource+"', '"+app.Image2.ImageSource+"','"+app.OgrTelefonNumaraEditField.Value+"','"+OgrenciBilgi.Vatandaslik+"','"+OgrenciBilgi.OgrYasam+"' ,'"+OgrenciBilgi.AileYasam+"','"+OgrenciBilgi.AnneCalismaDurum+"','"+OgrenciBilgi.BabaCalismaDurum+"','"+OgrenciBilgi.AileGelirDurum+"','"+OgrenciBilgi.OgrOkulu+"','"+OgrenciBilgi.BirliktelikDurumu+"','"+OgrenciBilgi.YasamDurumu+"','"+OgrenciBilgi.Edevlet+"','"+OgrenciBilgi.AileFert+"','"+OgrenciBilgi.YOokuyanFert+"','"+OgrenciBilgi.Programi+"','"+OgrenciBilgi.EgitimSurec+"','"+OgrenciBilgi.BursAlmaDurumu+"','"+OgrenciBilgi.DisiplinCeza+"','"+OgrenciBilgi.OgrenciCalisma+"','"+OgrenciBilgi.GayrimenkulDurumu+"','"+OgrenciBilgi.IsitmaDurumu+"','"+num2str(OgrenciBilgi.ToplamPuan)+"')";
           exec(conn, komut);
           close(conn)
           msgbox(sprintf('Cevaplarınız Başarılı Bir Şekilde Kaydedilmiştir.'), 'Değerlendirme Sonucu', 'modal');
          app.ogrenciler{end+1} = ogrenci;
        end

        % Button down function: CinsiyetinizNedir1ButtonGroup
        function CinsiyetinizNedir1ButtonGroupButtonDown(app, event)
           
        end

        % Close request function: BursiyerSecmeKararSistemiUIFigure
        function BursiyerSecmeKararSistemiUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: OgrenciBelgesiSecme
        function OgrenciBelgesiSecmePushed(app, event)
             % Resim seçimini kontrol et
[filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp;*.gif', 'Resim Dosyaları (*.jpg, *.png, *.bmp, *.gif)'; '*.*', 'Tüm Dosyalar (*.*)'}, 'Resim Seç');

% Kullanıcı resim seçerse devam et
if ~isequal(filename, 0)
    % Dosya yolu ve adını birleştir
    resimYolu = fullfile(pathname, filename);

    % Bir miktar bekleme ekle
    pause(0.1);

    % Seçilen resmi görüntü bileşenine ata
    app.Image2.ImageSource = resimYolu;

    % Görüntüyü güncelle ve pencereyi ön plana al
    drawnow;
    figure(app.BursiyerSecmeKararSistemiUIFigure);
else
    % Kullanıcı resim seçmezse mesaj göster
    msgbox('Ogrenci Belgesi seçilmedi.', 'Bilgi', 'modal');
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.BursiyerSecmeKararSistemiUIFigure = uifigure('Visible', 'off');
            app.BursiyerSecmeKararSistemiUIFigure.Name = 'BursiyerSecmeKararSistemi';
            app.BursiyerSecmeKararSistemiUIFigure.CloseRequestFcn = createCallbackFcn(app, @BursiyerSecmeKararSistemiUIFigureCloseRequest, true);
            app.CinsiyetinizNedir1ButtonGroup = uibuttongroup(app.BursiyerSecmeKararSistemiUIFigure);
            app.CinsiyetinizNedir1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CinsiyetinizNedir1ButtonGroupSelectionChanged, true);
            app.CinsiyetinizNedir1ButtonGroup.Title = 'Vatandaşlık Durumunuz?';
            app.CinsiyetinizNedir1ButtonGroup.ButtonDownFcn = createCallbackFcn(app, @CinsiyetinizNedir1ButtonGroupButtonDown, true);
            app.TurkiyeCumhuriyetiVatandasiButton = uiradiobutton(app.CinsiyetinizNedir1ButtonGroup);
            app.TurkiyeCumhuriyetiVatandasiButton.Text = 'Turkiye Cumhuriyeti Vatandasi';
            app.YabancUyrukluButton = uiradiobutton(app.CinsiyetinizNedir1ButtonGroup);
            app.YabancUyrukluButton.Text = 'Yabancı Uyruklu ';
            app.YabancUyrukluButton.Value = true;
            app.BursiyerSemeKararDestekSistemiLabel = uilabel(app.BursiyerSecmeKararSistemiUIFigure);
            app.BursiyerSemeKararDestekSistemiLabel.Text = 'Bursiyer Seçme Karar Destek Sistemi';
            app.Diplomabasarpuani2ButtonGroup = uibuttongroup(app.BursiyerSecmeKararSistemiUIFigure);
            app.Diplomabasarpuani2ButtonGroup.Title = 'Ogrencinin Ikametgah Durumu';
            app.AileyanindaButton = uiradiobutton(app.Diplomabasarpuani2ButtonGroup);
            app.AileyanindaButton.Text = 'Aile yaninda';
            app.AileyanindaButton.Value = true;
            app.KiradaButton = uiradiobutton(app.Diplomabasarpuani2ButtonGroup);
            app.KiradaButton.Text = 'Kirada';
            app.OzelyurtdaButton = uiradiobutton(app.Diplomabasarpuani2ButtonGroup);
            app.OzelyurtdaButton.Text = 'Ozel yurtda';
            app.DevletyurdundaButton = uiradiobutton(app.Diplomabasarpuani2ButtonGroup);
            app.DevletyurdundaButton.Text = 'Devlet yurdunda';
            app.Aileningelirdurumu3ButtonGroup = uibuttongroup(app.BursiyerSecmeKararSistemiUIFigure);
            app.Aileningelirdurumu3ButtonGroup.Title = 'Ailenin Ikametgah Durumu';
            app.KendiEvindeButton = uiradiobutton(app.Aileningelirdurumu3ButtonGroup);
            app.KendiEvindeButton.Text = 'Kendi Evinde';
            app.KendiEvindeButton.Value = true;
            app.KiradaButton_2 = uiradiobutton(app.Aileningelirdurumu3ButtonGroup);
            app.KiradaButton_2.Text = 'Kirada';
            app.ogrenciCalismaButtonGroup = uibuttongroup(app.BursiyerSecmeKararSistemiUIFigure);
            app.ogrenciCalismaButtonGroup.Title = 'Kisi(ogrenci) calisma durumu';
            app.Calismiyor = uiradiobutton(app.ogrenciCalismaButtonGroup);
            app.Calismiyor.Text = 'Calisiyorum';
            app.Calismiyor.Value = true;
            app.AylikMaas = uiradiobutton(app.ogrenciCalismaButtonGroup);
            app.AylikMaas.Text = 'Aylık maasla bir iste calisiyorum';
            app.GunlukgeilirlibiristecalisiyorumButton = uiradiobutton(app.ogrenciCalismaButtonGroup);
            app.GunlukgeilirlibiristecalisiyorumButton.Text = 'Gunluk geilirli bir iste calisiyorum';
            app.CalismiyorButton = uiradiobutton(app.ogrenciCalismaButtonGroup);
            app.CalismiyorButton.Text = 'Calismiyor';
            app.bransBasari4ButtonGroup = uibuttongroup(app.BursiyerSecmeKararSistemiUIFigure);
            app.bransBasari4ButtonGroup.Title = 'Ogrencinin Ogrenim Gordugu Okulun Statusu';
            app.DevletuniversitesiprogramButton = uiradiobutton(app.bransBasari4ButtonGroup);
            app.DevletuniversitesiprogramButton.Text = 'Devlet universitesi programı';
            app.DevletuniversitesiprogramButton.Value = true;
            app.VakifProgrami = uiradiobutton(app.bransBasari4ButtonGroup);
            app.VakifProgrami.Text = 'Vakıf universitesi %100 burslu program';
            app.DigerProgram = uiradiobutton(app.bransBasari4ButtonGroup);
            app.DigerProgram.Text = 'Diger universiteler  program (Vakıf, Şirket)';
            app.Yabancdil5ButtonGroup = uibuttongroup(app.BursiyerSecmeKararSistemiUIFigure);
            app.Yabancdil5ButtonGroup.Title = 'Ogrencinin Anne-Babasının Birliktelik Durumu';
            app.AnneBababirlikteyayorButton = uiradiobutton(app.Yabancdil5ButtonGroup);
            app.AnneBababirlikteyayorButton.Text = 'Anne-Baba birlikte yaşıyor';
            app.AnneBababirlikteyayorButton.Value = true;
            app.AnneBabaAyrButton = uiradiobutton(app.Yabancdil5ButtonGroup);
            app.AnneBabaAyrButton.Text = 'Anne-Baba Ayrı';
            app.sanatBransOdul6ButtonGroup = uibuttongroup(app.BursiyerSecmeKararSistemiUIFigure);
            app.sanatBransOdul6ButtonGroup.Title = 'Anne Calisma Durumu';
            app.annecalisiyor = uiradiobutton(app.sanatBransOdul6ButtonGroup);
            app.annecalisiyor.Text = 'Calisiyor';
            app.annecalisiyor.Value = true;
            app.AnneCalismiyor = uiradiobutton(app.sanatBransOdul6ButtonGroup);
            app.AnneCalismiyor.Text = 'Calismiyor';
            app.AnneEmekli = uiradiobutton(app.sanatBransOdul6ButtonGroup);
            app.AnneEmekli.Text = 'Emekli';
            app.ProgramiButtonGroup = uibuttongroup(app.BursiyerSecmeKararSistemiUIFigure);
            app.ProgramiButtonGroup.Title = 'Hangi Programı Okuyor Durumu';
            app.OnLisans = uiradiobutton(app.ProgramiButtonGroup);
            app.OnLisans.Text = 'OnLisans';
            app.OnLisans.Value = true;
            app.Lisans = uiradiobutton(app.ProgramiButtonGroup);
            app.Lisans.Text = 'Lisans';
            app.YuksekLisans = uiradiobutton(app.ProgramiButtonGroup);
            app.YuksekLisans.Text = 'YuksekLisans';
            app.BursuAlmaButtonGroup = uibuttongroup(app.BursiyerSecmeKararSistemiUIFigure);
            app.BursuAlmaButtonGroup.Title = 'Daha Once Burs Alma Durumu';
            app.Evetburs = uiradiobutton(app.BursuAlmaButtonGroup);
            app.Evetburs.Text = 'Evet';
            app.Evetburs.Value = true;
            app.Hayirburs = uiradiobutton(app.BursuAlmaButtonGroup);
            app.Hayirburs.Text = 'Hayır';
            app.ogrenciokuyanYOButtonGroup = uibuttongroup(app.BursiyerSecmeKararSistemiUIFigure);
            app.ogrenciokuyanYOButtonGroup.Title = 'Ogrenci Disinda Yuksekogretimde Okuyan Kisi Sayisi';
            app.BIR = uiradiobutton(app.ogrenciokuyanYOButtonGroup);
            app.BIR.Text = '1';
            app.BIR.Value = true;
            app.IKI = uiradiobutton(app.ogrenciokuyanYOButtonGroup);
            app.IKI.Text = '2';
            app.UC = uiradiobutton(app.ogrenciokuyanYOButtonGroup);
            app.UC.Text = '3';
            app.DORTVEUZERI = uiradiobutton(app.ogrenciokuyanYOButtonGroup);
            app.DORTVEUZERI.Text = '4 ve fazlası';
            app.edevletButtonGroup = uibuttongroup(app.BursiyerSecmeKararSistemiUIFigure);
            app.edevletButtonGroup.Title = 'E-Devlet Kullanım Durumu';
            app.Eveet = uiradiobutton(app.edevletButtonGroup);
            app.Eveet.Text = 'Evet';
            app.Eveet.Value = true;
            app.Hayir = uiradiobutton(app.edevletButtonGroup);
            app.Hayir.Text = 'Hayır';
            app.egitimsurecButtonGroup = uibuttongroup(app.BursiyerSecmeKararSistemiUIFigure);
            app.egitimsurecButtonGroup.Title = 'Normal Eğitim Süresi Içindemi Okuyor Durumu';
            app.Orgun = uiradiobutton(app.egitimsurecButtonGroup);
            app.Orgun.Text = 'Orgun Egitim';
            app.Orgun.Value = true;
            app.Uzaktan = uiradiobutton(app.egitimsurecButtonGroup);
            app.Uzaktan.Text = 'Uzaktan Egitim';
            app.ailefertButtonGroup = uibuttongroup(app.BursiyerSecmeKararSistemiUIFigure);
            app.ailefertButtonGroup.Title = 'Ogrenci Disindaki Aile Fert Sayisi';
            app.biraile = uiradiobutton(app.ailefertButtonGroup);
            app.biraile.Text = '1';
            app.biraile.Value = true;
            app.ikiaile = uiradiobutton(app.ailefertButtonGroup);
            app.ikiaile.Text = '2';
            app.ucaile = uiradiobutton(app.ailefertButtonGroup);
            app.ucaile.Text = '3';
            app.dortveuzeriaile = uiradiobutton(app.ailefertButtonGroup);
            app.dortveuzeriaile.Text = '4 ve fazlası';
            app.DisiplinButtonGroup = uibuttongroup(app.BursiyerSecmeKararSistemiUIFigure);
            app.DisiplinButtonGroup.Title = 'Disiplin Ceza Olma Durumu';
            app.Var = uiradiobutton(app.DisiplinButtonGroup);
            app.Var.Text = 'Var';
            app.Var.Value = true;
            app.Yok = uiradiobutton(app.DisiplinButtonGroup);
            app.Yok.Text = 'Yok';
            app.KaydetveGrntleButton = uibutton(app.BursiyerSecmeKararSistemiUIFigure, 'push');
            app.KaydetveGrntleButton.ButtonPushedFcn = createCallbackFcn(app, @KaydetveGrntleButtonPushed, true);
            app.KaydetveGrntleButton.Icon = fullfile(pathToMLAPP, 'indir.png');
            app.KaydetveGrntleButton.Text = 'Kaydet ve Görüntüle';
            app.AdSoyadEditFieldLabel = uilabel(app.BursiyerSecmeKararSistemiUIFigure);
            app.AdSoyadEditFieldLabel.Text = 'Ad Soyad';
            app.OgrAdSoyadEditField = uieditfield(app.BursiyerSecmeKararSistemiUIFigure, 'text');
            app.Image = uiimage(app.BursiyerSecmeKararSistemiUIFigure);
            app.ResminiziSecinizButton = uibutton(app.BursiyerSecmeKararSistemiUIFigure, 'push');
            app.ResminiziSecinizButton.ButtonPushedFcn = createCallbackFcn(app, @ResminiziSecinizButtonPushed, true);
            app.ResminiziSecinizButton.Text = 'Resminizi Seçiniz';
            app.TCLabel = uilabel(app.BursiyerSecmeKararSistemiUIFigure);
            app.TCLabel.Text = 'TC';
            app.OgrTCEditField = uieditfield(app.BursiyerSecmeKararSistemiUIFigure, 'text');
            app.TelefonNumaraLabel = uilabel(app.BursiyerSecmeKararSistemiUIFigure);
            app.TelefonNumaraLabel.Text = 'Telefon Numara';
            app.OgrTelefonNumaraEditField = uieditfield(app.BursiyerSecmeKararSistemiUIFigure, 'text');
            app.Image2 = uiimage(app.BursiyerSecmeKararSistemiUIFigure);
            app.OgrenciBelgesiSecme = uibutton(app.BursiyerSecmeKararSistemiUIFigure, 'push');
            app.OgrenciBelgesiSecme.ButtonPushedFcn = createCallbackFcn(app, @OgrenciBelgesiSecmePushed, true);
            app.OgrenciBelgesiSecme.Text = 'Ogrenci Belgenizi Seciniz';
            app.sanatBransOdul6ButtonGroup_2 = uibuttongroup(app.BursiyerSecmeKararSistemiUIFigure);
            app.sanatBransOdul6ButtonGroup_2.Title = 'Baba Calisma Durumu';
            app.babaCalisiyor = uiradiobutton(app.sanatBransOdul6ButtonGroup_2);
            app.babaCalisiyor.Text = 'Calisiyor';
            app.babaCalisiyor.Value = true;
            app.BabaCalismiyor = uiradiobutton(app.sanatBransOdul6ButtonGroup_2);
            app.BabaCalismiyor.Text = 'Calismiyor';
            app.BabaEmekli = uiradiobutton(app.sanatBransOdul6ButtonGroup_2);
            app.BabaEmekli.Text = 'Emekli';
            app.sanatBransOdul6ButtonGroup_3 = uibuttongroup(app.BursiyerSecmeKararSistemiUIFigure);
            app.sanatBransOdul6ButtonGroup_3.Title = 'Ailenin Aylık Geliri';
            app.Asgariucrettenaz = uiradiobutton(app.sanatBransOdul6ButtonGroup_3);
            app.Asgariucrettenaz.Text = 'Asgari ucretten az';
            app.Asgariucrettenaz.Value = true;
            app.AsgariucretButton = uiradiobutton(app.sanatBransOdul6ButtonGroup_3);
            app.AsgariucretButton.Text = 'Asgari ucret';
            app.AsgariucretussuButton = uiradiobutton(app.sanatBransOdul6ButtonGroup_3);
            app.AsgariucretussuButton.Text = 'Asgari ucret ussu';
            app.Yabancdil5ButtonGroup_2 = uibuttongroup(app.BursiyerSecmeKararSistemiUIFigure);
            app.Yabancdil5ButtonGroup_2.Title = 'Ogrencinin Anne-Babasının Yasam Durumu';
            app.babavefat = uiradiobutton(app.Yabancdil5ButtonGroup_2);
            app.babavefat.Text = 'Baba Vefat Etti';
            app.babavefat.Value = true;
            app.annevefat = uiradiobutton(app.Yabancdil5ButtonGroup_2);
            app.annevefat.Text = 'Anne Vefat Etti';
            app.IkisideVefat = uiradiobutton(app.Yabancdil5ButtonGroup_2);
            app.IkisideVefat.Text = 'Ikiside Vefat etti';
            app.ArsaButtonGroup_2 = uibuttongroup(app.BursiyerSecmeKararSistemiUIFigure);
            app.ArsaButtonGroup_2.Title = 'Ailenizin arsa,bag,bahce,gibi gayrimenkulu var mı?';
            app.Evetarsa = uiradiobutton(app.ArsaButtonGroup_2);
            app.Evetarsa.Text = 'Evet';
            app.Evetarsa.Value = true;
            app.Hayirarsa = uiradiobutton(app.ArsaButtonGroup_2);
            app.Hayirarsa.Text = 'Hayır';
            app.isitma = uibuttongroup(app.BursiyerSecmeKararSistemiUIFigure);
            app.isitma.Title = 'Oturdugunuz evi ne ile ısıtıyorsunuz?';
            app.kalorifer = uiradiobutton(app.isitma);
            app.kalorifer.Text = 'Kalorifer';
            app.kalorifer.Value = true;
            app.soba = uiradiobutton(app.isitma);
            app.soba.Text = 'Soba';
            % Show the figure after all components are created
            app.BursiyerSecmeKararSistemiUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BursiyerSecmeSistemi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BursiyerSecmeKararSistemiUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BursiyerSecmeKararSistemiUIFigure)
        end
    end
end