classdef SonucMatris < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SonucMatrisiUIFigure      matlab.ui.Figure
        UITable                   matlab.ui.control.Table
        BursKazanan50grenciLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    conn = database('BURSIYERSECMEKARARDESTEK', '', '');
komut = 'SELECT TOP 50 AdSoyad, OgrTelefonnNumarasi,ToplamPuan FROM [dbo].[Tbl_Ogrenci] ORDER BY CAST(ToplamPuan AS FLOAT) DESC';
tablo = fetch(conn, komut);
app.UITable.Data = tablo;
close(conn);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SonucMatrisiUIFigure = uifigure('Visible', 'off');
            app.SonucMatrisiUIFigure.Name = 'Sonuc Matrisi';
            app.BursKazanan50grenciLabel = uilabel(app.SonucMatrisiUIFigure);
            app.BursKazanan50grenciLabel.Text = 'Burs  Kazanan 50 Ögrenci';
            app.UITable = uitable(app.SonucMatrisiUIFigure);
            app.UITable.ColumnName = {'Ad Soyad'; 'Telefon Numarası'; 'Toplam Puanı'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.SonucMatrisiUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SonucMatris

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SonucMatrisiUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SonucMatrisiUIFigure)
        end
    end
end