classdef YonetimPaneli < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        YonetimPaneliUIFigure           matlab.ui.Figure
        PUANGUNCELLEButton              matlab.ui.control.Button
        isitma                          matlab.ui.container.ButtonGroup
        SobaEditField                   matlab.ui.control.EditField
        SobaEditFieldLabel              matlab.ui.control.Label
        KaloriferEditField              matlab.ui.control.EditField
        KaloriferEditFieldLabel         matlab.ui.control.Label
        ArsaButtonGroup                 matlab.ui.container.ButtonGroup
        HayrEditField_3                 matlab.ui.control.EditField
        HayrEditField_3Label            matlab.ui.control.Label
        EvetEditField_3                 matlab.ui.control.EditField
        EvetEditField_3Label            matlab.ui.control.Label
        ogrenciCalismaButtonGroup       matlab.ui.container.ButtonGroup
        CalismiyorEditField_3           matlab.ui.control.EditField
        CalismiyorEditField_3Label      matlab.ui.control.Label
        GunlukgeilirliistecalismaEditField  matlab.ui.control.EditField
        GunlukgeilirliistecalismaEditFieldLabel  matlab.ui.control.Label
        AylkmaaslistecalismaEditField   matlab.ui.control.EditField
        AylkmaaslistecalismaEditFieldLabel  matlab.ui.control.Label
        CalisiyorumEditField            matlab.ui.control.EditField
        CalisiyorumEditFieldLabel       matlab.ui.control.Label
        DisiplinButtonGroup             matlab.ui.container.ButtonGroup
        YokEditField                    matlab.ui.control.EditField
        YokEditFieldLabel               matlab.ui.control.Label
        VarEditField                    matlab.ui.control.EditField
        VarEditFieldLabel               matlab.ui.control.Label
        BursuAlmaButtonGroup            matlab.ui.container.ButtonGroup
        HayrEditField_2                 matlab.ui.control.EditField
        HayrEditField_2Label            matlab.ui.control.Label
        EvetEditField_2                 matlab.ui.control.EditField
        EvetEditField_2Label            matlab.ui.control.Label
        egitimsurecButtonGroup          matlab.ui.container.ButtonGroup
        UzaktanEgitimEditField          matlab.ui.control.EditField
        UzaktanEgitimEditFieldLabel     matlab.ui.control.Label
        OrgunEgitimEditField            matlab.ui.control.EditField
        OrgunEgitimEditFieldLabel       matlab.ui.control.Label
        ProgramiButtonGroup             matlab.ui.container.ButtonGroup
        YuksekLisansEditField           matlab.ui.control.EditField
        YuksekLisansEditFieldLabel      matlab.ui.control.Label
        LisansEditField                 matlab.ui.control.EditField
        LisansEditFieldLabel            matlab.ui.control.Label
        OnLisansEditField               matlab.ui.control.EditField
        OnLisansEditFieldLabel          matlab.ui.control.Label
        ogrenciokuyanYOButtonGroup      matlab.ui.container.ButtonGroup
        vefazlaOgrEditField             matlab.ui.control.EditField
        vefazlaOgrEditFieldLabel        matlab.ui.control.Label
        OgrenciEditField_3              matlab.ui.control.EditField
        OgrenciLabel_2                  matlab.ui.control.Label
        OgrenciEditField_2              matlab.ui.control.EditField
        OgrenciLabel                    matlab.ui.control.Label
        OgrenciEditField                matlab.ui.control.EditField
        OgrenciEditFieldLabel           matlab.ui.control.Label
        ailefertButtonGroup             matlab.ui.container.ButtonGroup
        vefazlaKisiEditField            matlab.ui.control.EditField
        vefazlaKisiEditFieldLabel       matlab.ui.control.Label
        KisiEditField_3                 matlab.ui.control.EditField
        KisiEditField_3Label            matlab.ui.control.Label
        KisiEditField_2                 matlab.ui.control.EditField
        KisiEditField_2Label            matlab.ui.control.Label
        KisiEditField                   matlab.ui.control.EditField
        KisiEditFieldLabel              matlab.ui.control.Label
        edevletButtonGroup              matlab.ui.container.ButtonGroup
        HayrEditField                   matlab.ui.control.EditField
        HayrEditFieldLabel              matlab.ui.control.Label
        EvetEditField                   matlab.ui.control.EditField
        EvetEditFieldLabel              matlab.ui.control.Label
        Yabancdil5ButtonGroup_2         matlab.ui.container.ButtonGroup
        IkisideVefatettiEditField       matlab.ui.control.EditField
        IkisideVefatettiEditFieldLabel  matlab.ui.control.Label
        AnneVefatEttiEditField          matlab.ui.control.EditField
        AnneVefatEttiEditFieldLabel     matlab.ui.control.Label
        BabaVefatEttiEditField          matlab.ui.control.EditField
        BabaVefatEttiEditFieldLabel     matlab.ui.control.Label
        Yabancdil5ButtonGroup           matlab.ui.container.ButtonGroup
        AnneBabaAyrEditField            matlab.ui.control.EditField
        AnneBabaAyrEditFieldLabel       matlab.ui.control.Label
        AnneBababirlikteyayorEditField  matlab.ui.control.EditField
        AnneBababirlikteyayorEditFieldLabel  matlab.ui.control.Label
        bransBasari4ButtonGroup         matlab.ui.container.ButtonGroup
        DigeruniversitelerprogramVakfirketEditField  matlab.ui.control.EditField
        DigeruniversitelerprogramVakfirketEditFieldLabel  matlab.ui.control.Label
        Vakfuniversitesi100bursluprogramEditField  matlab.ui.control.EditField
        Vakfuniversitesi100bursluprogramEditFieldLabel  matlab.ui.control.Label
        DevletuniversitesiprogramEditField  matlab.ui.control.EditField
        DevletuniversitesiprogramEditFieldLabel  matlab.ui.control.Label
        sanatBransOdul6ButtonGroup_3    matlab.ui.container.ButtonGroup
        AsgariucretussuEditField        matlab.ui.control.EditField
        AsgariucretussuEditFieldLabel   matlab.ui.control.Label
        AsgariucretEditField            matlab.ui.control.EditField
        AsgariucretEditFieldLabel       matlab.ui.control.Label
        AsgariucrettenazEditField       matlab.ui.control.EditField
        AsgariucrettenazEditFieldLabel  matlab.ui.control.Label
        sanatBransOdul6ButtonGroup_2    matlab.ui.container.ButtonGroup
        EmekliEditField_2               matlab.ui.control.EditField
        EmekliEditField_2Label          matlab.ui.control.Label
        CalismiyorEditField_2           matlab.ui.control.EditField
        CalismiyorEditField_2Label      matlab.ui.control.Label
        CalisiyorEditField_2            matlab.ui.control.EditField
        CalisiyorEditField_2Label       matlab.ui.control.Label
        sanatBransOdul6ButtonGroup      matlab.ui.container.ButtonGroup
        EmekliEditField                 matlab.ui.control.EditField
        EmekliEditFieldLabel            matlab.ui.control.Label
        CalismiyorEditField             matlab.ui.control.EditField
        CalismiyorEditFieldLabel        matlab.ui.control.Label
        CalisiyorEditField              matlab.ui.control.EditField
        CalisiyorEditFieldLabel         matlab.ui.control.Label
        Aileningelirdurumu3ButtonGroup  matlab.ui.container.ButtonGroup
        KiradaEditField                 matlab.ui.control.EditField
        KiradaEditFieldLabel            matlab.ui.control.Label
        KendiEvindeEditField            matlab.ui.control.EditField
        KendiEvindeEditFieldLabel       matlab.ui.control.Label
        Diplomabasarpuani2ButtonGroup   matlab.ui.container.ButtonGroup
        DevletyurdundaEditField         matlab.ui.control.EditField
        DevletyurdundaEditFieldLabel    matlab.ui.control.Label
        OzelyurtdaEditField             matlab.ui.control.EditField
        OzelyurtdaEditFieldLabel        matlab.ui.control.Label
        KiradaEditField_2               matlab.ui.control.EditField
        KiradaEditField_2Label          matlab.ui.control.Label
        AileyanindaEditField            matlab.ui.control.EditField
        AileyanindaEditFieldLabel       matlab.ui.control.Label
        CinsiyetinizNedir1ButtonGroup   matlab.ui.container.ButtonGroup
        YabancUyrukluEditField          matlab.ui.control.EditField
        YabancUyrukluEditFieldLabel     matlab.ui.control.Label
        TurkiyeCumhuriyetiVatandasiEditField  matlab.ui.control.EditField
        TurkiyeCumhuriyetiVatandasiLabel  matlab.ui.control.Label
        YonetimPaneliLabel              matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %VERİTABANINI BAGLAMA
            conn=database('BURSIYERSECMEKARARDESTEK','','');
            komut = 'select [TcUyrukluPuan],[YabanciUyrukluPuan] ,[AileYanindaPuan] ,[KiradaPuan] ,[OzelYurtPuan]  ,[DevletYurtPuan] ,[KendiEvPuan] ,[KiraEvPuan] ,[AnneCalisiyorPuan],[AnneCalismiyorPuan],[AnneEmekliPuan],[BabaCalisiyorPuan],[BabaCalismiyorPuan],[BabaEmekliPuan],[AsgariUcrettenAzPuan],[AsgariUcretPuan] ,[AsgariUcretUssuPuan],[DevletUniPuan]  ,[VakifUniPuan],[DigerUniPuan],[AnneBabaBirliktePuan] ,[AnneBabaAyriPuan],[BabaVefatPuan] ,[AnneVefatPuan] ,[IkisideVefatPuan] ,[EDevletEvetPuan] ,[EDevletHayirPuan] ,[AileFert1Puan],[AileFert2Puan],[AileFert3Puan] ,[AileFert4veFazlasiPuan] ,[YOokuyan1Puan]  ,[YOokuyan2Puan] ,[YOokuyan3Puan] ,[YOokuyan4veFazlasiPuan] ,[OnlisansPuan],[LisansPuan],[YuksekLisansPuan],[OrgunEgitimPuan],[UzaktanEgitimPuan],[BursEvetPuan] ,[BursHayirPuan] ,[DisiplinVarPuan] ,[DisiplinYokPuan] ,[OgrCalisiyorPuan],[OgrAylikMaasPuan],[OgrGunlukGelirPuan] ,[OgrCalismiyorPuan] ,[GayrimenkulEvetPuan] ,[GayrimenkulHayirPuan] ,[IsitmaKaloriferPuan] ,[IsitmaSobaPuan] from [dbo].[Tbl_PUANN]';
            Data=fetch(conn,komut);
            Data=table2cell(Data);

          app.TurkiyeCumhuriyetiVatandasiEditField.Value=string(Data{1});
          app.YabancUyrukluEditField.Value=string(Data{2});
          app.AileyanindaEditField.Value=string(Data{3});
          app.KiradaEditField_2.Value=string(Data{4});
          app.OzelyurtdaEditField.Value=string(Data{5});
          app.DevletyurdundaEditField.Value=string(Data{6});
          app.KendiEvindeEditField.Value=string(Data{7});
          app.KiradaEditField.Value=string(Data{8});
          app.CalisiyorEditField.Value=string(Data{9});
          app.CalismiyorEditField.Value=string(Data{10});
          app.EmekliEditField.Value=string(Data{11});
          app.CalisiyorEditField_2.Value=string(Data{12});
          app.CalismiyorEditField_2.Value=string(Data{13});
          app.EmekliEditField_2.Value=string(Data{14});
          app.AsgariucrettenazEditField.Value=string(Data{15});
          app.AsgariucretEditField.Value=string(Data{16});
          app.AsgariucretussuEditField.Value=string(Data{17});
          app.DevletuniversitesiprogramEditField.Value=string(Data{18});
          app.Vakfuniversitesi100bursluprogramEditField.Value=string(Data{19});
          app.DigeruniversitelerprogramVakfirketEditField.Value=string(Data{20});
          app.AnneBababirlikteyayorEditField.Value=string(Data{21});
          app.AnneBabaAyrEditField.Value=string(Data{22});
          app.BabaVefatEttiEditField.Value=string(Data{23});
          app.AnneVefatEttiEditField.Value=string(Data{24});
          app.IkisideVefatettiEditField.Value=string(Data{25});
          app.EvetEditField.Value=string(Data{26});
          app.HayrEditField.Value=string(Data{27});
          app.KisiEditField.Value=string(Data{28});
          app.KisiEditField_2.Value=string(Data{29});
          app.KisiEditField_3.Value=string(Data{30});
          app.vefazlaKisiEditField.Value=string(Data{31});
          app.OgrenciEditField.Value=string(Data{32});
          app.OgrenciEditField_2.Value=string(Data{33});
          app.OgrenciEditField_3.Value=string(Data{34}); 
          app.vefazlaOgrEditField.Value=string(Data{35});
          app.OnLisansEditField.Value=string(Data{36});
          app.LisansEditField.Value=string(Data{37});
          app.YuksekLisansEditField.Value=string(Data{38});
          app.OrgunEgitimEditField.Value=string(Data{39});
          app.UzaktanEgitimEditField.Value=string(Data{40});
          app.EvetEditField_2.Value=string(Data{41});
          app.HayrEditField_2.Value=string(Data{42});
          app.VarEditField.Value=string(Data{43});
          app.YokEditField.Value=string(Data{44});
          app.CalisiyorumEditField.Value=string(Data{45});
          app.AylkmaaslistecalismaEditField.Value=string(Data{46});
          app.GunlukgeilirliistecalismaEditField.Value=string(Data{47});
          app.CalismiyorEditField_3.Value=string(Data{48});
          app.EvetEditField_3.Value=string(Data{49});
          app.HayrEditField_3.Value=string(Data{50});
          app.KaloriferEditField.Value=string(Data{51});
          app.SobaEditField.Value=string(Data{52});

         % Bir miktar bekleme ekle
    pause(0.1);

    % Görüntüyü güncelle ve pencereyi ön plana al
    drawnow;
    figure(app.YonetimPaneliUIFigure);
   

         % Veritabanı bağlantısını kapatma
          close(conn);

        end

        % Button pushed function: PUANGUNCELLEButton
        function PUANGUNCELLEButtonPushed(app, event)
%             VERİTABANINI BAGLAMA
            conn=database('BURSIYERSECMEKARARDESTEK','','');
            TcUyrukluPuan=str2double(app.TurkiyeCumhuriyetiVatandasiEditField.Value);
            YabanciUyrukluPuan=str2double(app.YabancUyrukluEditField.Value);
            AileYanindaPuan=str2double(app.AileyanindaEditField.Value);
            KiradaPuan=str2double(app.KiradaEditField_2.Value);
            OzelYurtPuan=str2double(app.OzelyurtdaEditField.Value);
            DevletYurtPuan=str2double(app.DevletyurdundaEditField.Value);
            KendiEvPuan=str2double(app.KendiEvindeEditField.Value);
            KiraEvPuan=str2double(app.KiradaEditField.Value);
            AnneCalisiyorPuan=str2double(app.CalisiyorEditField.Value);
            AnneCalismiyorPuan=str2double(app.CalismiyorEditField.Value);
            AnneEmekliPuan=str2double(app.EmekliEditField.Value);
            BabaCalisiyorPuan=str2double(app.CalisiyorEditField_2.Value);
            BabaCalismiyorPuan=str2double(app.CalismiyorEditField_2.Value);
            BabaEmekliPuan=str2double(app.EmekliEditField_2.Value);
            AsgariUcrettenAzPuan=str2double(app.AsgariucrettenazEditField.Value);
            AsgariUcretPuan=str2double(app.AsgariucretEditField.Value);
            AsgariUcretUssuPuan=str2double(app.AsgariucretussuEditField.Value);
            DevletUniPuan=str2double(app.DevletuniversitesiprogramEditField.Value);
            VakifUniPuan=str2double(app.Vakfuniversitesi100bursluprogramEditField.Value);
            DigerUniPuan=str2double(app.DigeruniversitelerprogramVakfirketEditField.Value);
            AnneBabaBirliktePuan=str2double(app.AnneBababirlikteyayorEditField.Value);
            AnneBabaAyriPuan=str2double(app.AnneBabaAyrEditField.Value);
            BabaVefatPuan=str2double(app.BabaVefatEttiEditField.Value);
            AnneVefatPuan=str2double(app.AnneVefatEttiEditField.Value);
            IkisideVefatPuan=str2double(app.IkisideVefatettiEditField.Value);
            EDevletEvetPuan=str2double(app.EvetEditField.Value);
            EDevletHayirPuan=str2double(app.HayrEditField.Value);
            AileFert1Puan=str2double(app.KisiEditField.Value);
            AileFert2Puan=str2double(app.KisiEditField_2.Value);
            AileFert3Puan=str2double(app.KisiEditField_3.Value);
            AileFert4veFazlasiPuan=str2double(app.vefazlaKisiEditField.Value);
            YOokuyan1Puan=str2double(app.OgrenciEditField.Value);
            YOokuyan2Puan=str2double(app.OgrenciEditField_2.Value);
            YOokuyan3Puan=str2double(app.OgrenciEditField_3.Value);
            YOokuyan4veFazlasiPuan=str2double(app.vefazlaOgrEditField.Value);
            OnlisansPuan=str2double(app.OnLisansEditField.Value);
            LisansPuan=str2double(app.LisansEditField.Value);
            YuksekLisansPuan=str2double(app.YuksekLisansEditField.Value);
            OrgunEgitimPuan=str2double(app.OrgunEgitimEditField.Value);
            UzaktanEgitimPuan=str2double(app.UzaktanEgitimEditField.Value);
            BursEvetPuan=str2double(app.EvetEditField_2.Value);
            BursHayirPuan=str2double(app.HayrEditField_2.Value);
            DisiplinVarPuan=str2double(app.VarEditField.Value);
            DisiplinYokPuan=str2double(app.YokEditField.Value);
            OgrCalisiyorPuan=str2double(app.CalisiyorumEditField.Value);
            OgrAylikMaasPuan=str2double(app.AylkmaaslistecalismaEditField.Value);
            OgrGunlukGelirPuan=str2double(app.GunlukgeilirliistecalismaEditField.Value);
            OgrCalismiyorPuan=str2double(app.CalismiyorEditField_3.Value);
            GayrimenkulEvetPuan=str2double(app.EvetEditField_3.Value);
            GayrimenkulHayirPuan=str2double(app.HayrEditField_3.Value);
            IsitmaKaloriferPuan=str2double(app.KaloriferEditField.Value);
            IsitmaSobaPuan=str2double(app.SobaEditField.Value);


   komut="Update [dbo].[Tbl_PUANN] set TcUyrukluPuan="+TcUyrukluPuan+" ,YabanciUyrukluPuan="+YabanciUyrukluPuan+" ,AileYanindaPuan="+AileYanindaPuan+" ,KiradaPuan="+KiradaPuan+",OzelYurtPuan="+OzelYurtPuan+",DevletYurtPuan="+DevletYurtPuan+",KendiEvPuan="+KendiEvPuan+",KiraEvPuan="+KiraEvPuan+",AnneCalisiyorPuan="+AnneCalisiyorPuan+",AnneCalismiyorPuan="+AnneCalismiyorPuan+",AnneEmekliPuan="+AnneEmekliPuan+",BabaCalisiyorPuan="+BabaCalisiyorPuan+",BabaCalismiyorPuan="+BabaCalismiyorPuan+",BabaEmekliPuan="+BabaEmekliPuan+",AsgariUcrettenAzPuan="+AsgariUcrettenAzPuan+",AsgariUcretPuan="+AsgariUcretPuan+",AsgariUcretUssuPuan="+AsgariUcretUssuPuan+",DevletUniPuan="+DevletUniPuan+",VakifUniPuan="+VakifUniPuan+",DigerUniPuan="+DigerUniPuan+",AnneBabaBirliktePuan="+AnneBabaBirliktePuan+",AnneBabaAyriPuan="+AnneBabaAyriPuan+",BabaVefatPuan="+BabaVefatPuan+",AnneVefatPuan="+AnneVefatPuan+",IkisideVefatPuan="+IkisideVefatPuan+",EDevletEvetPuan="+EDevletEvetPuan+",EDevletHayirPuan="+EDevletHayirPuan+",AileFert1Puan="+AileFert1Puan+",AileFert2Puan="+AileFert2Puan+",AileFert3Puan="+AileFert3Puan+",AileFert4veFazlasiPuan="+AileFert4veFazlasiPuan+",YOokuyan1Puan="+YOokuyan1Puan+",YOokuyan2Puan="+YOokuyan2Puan+",YOokuyan3Puan="+YOokuyan3Puan+",YOokuyan4veFazlasiPuan="+YOokuyan4veFazlasiPuan+",OnlisansPuan="+OnlisansPuan+",LisansPuan="+LisansPuan+",YuksekLisansPuan="+YuksekLisansPuan+" ,OrgunEgitimPuan="+OrgunEgitimPuan+",UzaktanEgitimPuan="+UzaktanEgitimPuan+",BursEvetPuan="+BursEvetPuan+",BursHayirPuan="+BursHayirPuan+",DisiplinVarPuan="+DisiplinVarPuan+",DisiplinYokPuan="+DisiplinYokPuan+",OgrCalisiyorPuan="+OgrCalisiyorPuan+",OgrAylikMaasPuan="+OgrAylikMaasPuan+",OgrGunlukGelirPuan="+OgrGunlukGelirPuan+",OgrCalismiyorPuan="+OgrCalismiyorPuan+",GayrimenkulEvetPuan="+GayrimenkulEvetPuan+",GayrimenkulHayirPuan="+GayrimenkulHayirPuan+",IsitmaKaloriferPuan="+IsitmaKaloriferPuan+",IsitmaSobaPuan="+IsitmaSobaPuan+" where ID=1";
% Bir miktar bekleme ekle
    pause(0.1);
    % Görüntüyü güncelle ve pencereyi ön plana al
    drawnow;
    figure(app.YonetimPaneliUIFigure);
   % Veritabanında güncelleme yapma
          execute(conn, komut);
% Veritabanı bağlantısını kapatma
    close(conn);
    msgbox(sprintf('Puanlarınız Başarılı Bir Şekilde Güncellenmiştir.'), 'Değişim Sonucu', 'modal');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.YonetimPaneliUIFigure = uifigure('Visible', 'off');
            app.YonetimPaneliUIFigure.Name = 'Yonetim Paneli';
            app.YonetimPaneliLabel = uilabel(app.YonetimPaneliUIFigure);
            app.YonetimPaneliLabel.Text = 'Yonetim Paneli';
            app.CinsiyetinizNedir1ButtonGroup = uibuttongroup(app.YonetimPaneliUIFigure);
            app.CinsiyetinizNedir1ButtonGroup.Title = 'Vatandaşlık Durumu Puanı Güncelleme ';
            app.TurkiyeCumhuriyetiVatandasiLabel = uilabel(app.CinsiyetinizNedir1ButtonGroup);
            app.TurkiyeCumhuriyetiVatandasiLabel.Text = 'Turkiye Cumhuriyeti Vatandasi  ';
            app.TurkiyeCumhuriyetiVatandasiEditField = uieditfield(app.CinsiyetinizNedir1ButtonGroup, 'text');
            app.YabancUyrukluEditFieldLabel = uilabel(app.CinsiyetinizNedir1ButtonGroup);
            app.YabancUyrukluEditFieldLabel.Text = 'Yabancı Uyruklu ';
            app.YabancUyrukluEditField = uieditfield(app.CinsiyetinizNedir1ButtonGroup, 'text');
            app.Diplomabasarpuani2ButtonGroup = uibuttongroup(app.YonetimPaneliUIFigure);
            app.Diplomabasarpuani2ButtonGroup.Title = 'Ogrencinin Ikametgah Durumu Puanı Güncelleme ';
            app.AileyanindaEditFieldLabel = uilabel(app.Diplomabasarpuani2ButtonGroup);
            app.AileyanindaEditFieldLabel.Text = 'Aile yaninda ';
            app.AileyanindaEditField = uieditfield(app.Diplomabasarpuani2ButtonGroup, 'text');
            app.KiradaEditField_2Label = uilabel(app.Diplomabasarpuani2ButtonGroup);
            app.KiradaEditField_2Label.Text = 'Kirada  ';
            app.KiradaEditField_2 = uieditfield(app.Diplomabasarpuani2ButtonGroup, 'text');
            app.OzelyurtdaEditFieldLabel = uilabel(app.Diplomabasarpuani2ButtonGroup);
            app.OzelyurtdaEditFieldLabel.Text = 'Ozel yurtda ';
            app.OzelyurtdaEditField = uieditfield(app.Diplomabasarpuani2ButtonGroup, 'text');
            app.DevletyurdundaEditFieldLabel = uilabel(app.Diplomabasarpuani2ButtonGroup);
            app.DevletyurdundaEditFieldLabel.Text = 'Devlet yurdunda  ';
            app.DevletyurdundaEditField = uieditfield(app.Diplomabasarpuani2ButtonGroup, 'text');
            app.Aileningelirdurumu3ButtonGroup = uibuttongroup(app.YonetimPaneliUIFigure);
            app.Aileningelirdurumu3ButtonGroup.Title = 'Ailenin Ikametgah Durumu Puanı Güncelleme ';
            app.KendiEvindeEditFieldLabel = uilabel(app.Aileningelirdurumu3ButtonGroup);
            app.KendiEvindeEditFieldLabel.Text = 'Kendi Evinde';
            app.KendiEvindeEditField = uieditfield(app.Aileningelirdurumu3ButtonGroup, 'text');
            app.KiradaEditFieldLabel = uilabel(app.Aileningelirdurumu3ButtonGroup);
            app.KiradaEditFieldLabel.Text = 'Kirada';
            app.KiradaEditField = uieditfield(app.Aileningelirdurumu3ButtonGroup, 'text');
            app.sanatBransOdul6ButtonGroup = uibuttongroup(app.YonetimPaneliUIFigure);
            app.sanatBransOdul6ButtonGroup.Title = 'Anne Calisma Durumu Puanı Güncelleme ';
            app.CalisiyorEditFieldLabel = uilabel(app.sanatBransOdul6ButtonGroup);
            app.CalisiyorEditFieldLabel.Text = 'Calisiyor';
            app.CalisiyorEditField = uieditfield(app.sanatBransOdul6ButtonGroup, 'text');
            app.CalismiyorEditFieldLabel = uilabel(app.sanatBransOdul6ButtonGroup);
            app.CalismiyorEditFieldLabel.Text = 'Calismiyor';
            app.CalismiyorEditField = uieditfield(app.sanatBransOdul6ButtonGroup, 'text');
            app.EmekliEditFieldLabel = uilabel(app.sanatBransOdul6ButtonGroup);
            app.EmekliEditFieldLabel.Text = 'Emekli';
            app.EmekliEditField = uieditfield(app.sanatBransOdul6ButtonGroup, 'text');
            app.sanatBransOdul6ButtonGroup_2 = uibuttongroup(app.YonetimPaneliUIFigure);
            app.sanatBransOdul6ButtonGroup_2.Title = 'Baba Calisma Durumu Puanı Güncelleme ';
            app.CalisiyorEditField_2Label = uilabel(app.sanatBransOdul6ButtonGroup_2);
            app.CalisiyorEditField_2Label.Text = 'Calisiyor';
            app.CalisiyorEditField_2 = uieditfield(app.sanatBransOdul6ButtonGroup_2, 'text');
            app.CalismiyorEditField_2Label = uilabel(app.sanatBransOdul6ButtonGroup_2);
            app.CalismiyorEditField_2Label.Text = 'Calismiyor';
            app.CalismiyorEditField_2 = uieditfield(app.sanatBransOdul6ButtonGroup_2, 'text');
            app.EmekliEditField_2Label = uilabel(app.sanatBransOdul6ButtonGroup_2);
            app.EmekliEditField_2Label.Text = 'Emekli';
            app.EmekliEditField_2 = uieditfield(app.sanatBransOdul6ButtonGroup_2, 'text');
            app.sanatBransOdul6ButtonGroup_3 = uibuttongroup(app.YonetimPaneliUIFigure);
            app.sanatBransOdul6ButtonGroup_3.Title = 'Ailenin Aylık Geliri Puanı Güncelleme ';
            app.AsgariucrettenazEditFieldLabel = uilabel(app.sanatBransOdul6ButtonGroup_3);
            app.AsgariucrettenazEditFieldLabel.Text = 'Asgari ucretten az';
            app.AsgariucrettenazEditField = uieditfield(app.sanatBransOdul6ButtonGroup_3, 'text');
            app.AsgariucretEditFieldLabel = uilabel(app.sanatBransOdul6ButtonGroup_3);
            app.AsgariucretEditFieldLabel.Text = 'Asgari ucret';
            app.AsgariucretEditField = uieditfield(app.sanatBransOdul6ButtonGroup_3, 'text');
            app.AsgariucretussuEditFieldLabel = uilabel(app.sanatBransOdul6ButtonGroup_3);
            app.AsgariucretussuEditFieldLabel.Text = 'Asgari ucret ussu';
            app.AsgariucretussuEditField = uieditfield(app.sanatBransOdul6ButtonGroup_3, 'text');
            app.bransBasari4ButtonGroup = uibuttongroup(app.YonetimPaneliUIFigure);
            app.bransBasari4ButtonGroup.Title = 'Ogrencinin Ogrenim Gordugu Okulun Statusu Puanı Güncelleme ';
            app.DevletuniversitesiprogramEditFieldLabel = uilabel(app.bransBasari4ButtonGroup);
            app.DevletuniversitesiprogramEditFieldLabel.Text = 'Devlet universitesi programı';
            app.DevletuniversitesiprogramEditField = uieditfield(app.bransBasari4ButtonGroup, 'text');
            app.Vakfuniversitesi100bursluprogramEditFieldLabel = uilabel(app.bransBasari4ButtonGroup);
            app.Vakfuniversitesi100bursluprogramEditFieldLabel.Text = 'Vakıf universitesi %100 burslu program';
            app.Vakfuniversitesi100bursluprogramEditField = uieditfield(app.bransBasari4ButtonGroup, 'text');
            app.DigeruniversitelerprogramVakfirketEditFieldLabel = uilabel(app.bransBasari4ButtonGroup);
            app.DigeruniversitelerprogramVakfirketEditFieldLabel.Text = 'Diger universiteler  program (Vakıf, Şirket)';
            app.DigeruniversitelerprogramVakfirketEditField = uieditfield(app.bransBasari4ButtonGroup, 'text');
            app.Yabancdil5ButtonGroup = uibuttongroup(app.YonetimPaneliUIFigure);
            app.Yabancdil5ButtonGroup.Title = 'Ogrencinin Anne-Babasının Birliktelik Durumu Puanı Güncelleme ';
            app.AnneBababirlikteyayorEditFieldLabel = uilabel(app.Yabancdil5ButtonGroup);
            app.AnneBababirlikteyayorEditFieldLabel.Text = 'Anne-Baba birlikte yaşıyor';
            app.AnneBababirlikteyayorEditField = uieditfield(app.Yabancdil5ButtonGroup, 'text');
            app.AnneBabaAyrEditFieldLabel = uilabel(app.Yabancdil5ButtonGroup);
            app.AnneBabaAyrEditFieldLabel.Text = 'Anne-Baba Ayrı';
            app.AnneBabaAyrEditField = uieditfield(app.Yabancdil5ButtonGroup, 'text');
            app.Yabancdil5ButtonGroup_2 = uibuttongroup(app.YonetimPaneliUIFigure);
            app.Yabancdil5ButtonGroup_2.Title = 'Ogrencinin Anne-Babasının Yasam Durumu Puanı Güncelleme ';
            app.BabaVefatEttiEditFieldLabel = uilabel(app.Yabancdil5ButtonGroup_2);
            app.BabaVefatEttiEditFieldLabel.Text = 'Baba Vefat Etti';
            app.BabaVefatEttiEditField = uieditfield(app.Yabancdil5ButtonGroup_2, 'text');
            app.AnneVefatEttiEditFieldLabel = uilabel(app.Yabancdil5ButtonGroup_2);
            app.AnneVefatEttiEditFieldLabel.Text = 'Anne Vefat Etti';
            app.AnneVefatEttiEditField = uieditfield(app.Yabancdil5ButtonGroup_2, 'text');
            app.IkisideVefatettiEditFieldLabel = uilabel(app.Yabancdil5ButtonGroup_2);
            app.IkisideVefatettiEditFieldLabel.Text = 'Ikiside Vefat etti';
            app.IkisideVefatettiEditField = uieditfield(app.Yabancdil5ButtonGroup_2, 'text');
            app.edevletButtonGroup = uibuttongroup(app.YonetimPaneliUIFigure);
            app.edevletButtonGroup.Title = 'E-Devlet Kullanım Durumu Puanı Güncelleme ';
            app.EvetEditFieldLabel = uilabel(app.edevletButtonGroup);
            app.EvetEditFieldLabel.Text = 'Evet';
            app.EvetEditField = uieditfield(app.edevletButtonGroup, 'text');
            app.HayrEditFieldLabel = uilabel(app.edevletButtonGroup);
            app.HayrEditFieldLabel.Text = 'Hayır';
            app.HayrEditField = uieditfield(app.edevletButtonGroup, 'text');
            app.ailefertButtonGroup = uibuttongroup(app.YonetimPaneliUIFigure);
            app.ailefertButtonGroup.Title = 'Ogrenci Disindaki Aile Fert Sayisi Puanı Güncelleme ';
            app.KisiEditFieldLabel = uilabel(app.ailefertButtonGroup);
            app.KisiEditFieldLabel.Text = '1 Kisi';
            app.KisiEditField = uieditfield(app.ailefertButtonGroup, 'text');
            app.KisiEditField_2Label = uilabel(app.ailefertButtonGroup);
            app.KisiEditField_2Label.Text = '2 Kisi';
            app.KisiEditField_2 = uieditfield(app.ailefertButtonGroup, 'text');
            app.KisiEditField_3Label = uilabel(app.ailefertButtonGroup);
            app.KisiEditField_3Label.Text = '3 Kisi';
            app.KisiEditField_3 = uieditfield(app.ailefertButtonGroup, 'text');
            app.vefazlaKisiEditFieldLabel = uilabel(app.ailefertButtonGroup);
            app.vefazlaKisiEditFieldLabel.Text = '4 ve fazla Kisi';
            app.vefazlaKisiEditField = uieditfield(app.ailefertButtonGroup, 'text');
            app.ogrenciokuyanYOButtonGroup = uibuttongroup(app.YonetimPaneliUIFigure);
            app.ogrenciokuyanYOButtonGroup.Title = 'Ogrenci Disinda Yuksekogretimde Okuyan Kisi Sayisi Puanı Güncelleme ';
            app.OgrenciEditFieldLabel = uilabel(app.ogrenciokuyanYOButtonGroup);
            app.OgrenciEditFieldLabel.Text = '1 Ogrenci';
            app.OgrenciEditField = uieditfield(app.ogrenciokuyanYOButtonGroup, 'text');
            app.OgrenciLabel = uilabel(app.ogrenciokuyanYOButtonGroup);
            app.OgrenciLabel.Text = '2 Ogrenci';
            app.OgrenciEditField_2 = uieditfield(app.ogrenciokuyanYOButtonGroup, 'text');
            app.OgrenciLabel_2 = uilabel(app.ogrenciokuyanYOButtonGroup);
            app.OgrenciLabel_2.Text = '3 Ogrenci';
            app.OgrenciEditField_3 = uieditfield(app.ogrenciokuyanYOButtonGroup, 'text');
            app.vefazlaOgrEditFieldLabel = uilabel(app.ogrenciokuyanYOButtonGroup);
            app.vefazlaOgrEditFieldLabel.Text = '4 ve fazla Ogr.';
            app.vefazlaOgrEditField = uieditfield(app.ogrenciokuyanYOButtonGroup, 'text');
            app.ProgramiButtonGroup = uibuttongroup(app.YonetimPaneliUIFigure);
            app.ProgramiButtonGroup.Title = 'Hangi Programı Okuyor Durumu Puanı Güncelleme ';
            app.OnLisansEditFieldLabel = uilabel(app.ProgramiButtonGroup);
            app.OnLisansEditFieldLabel.Text = 'OnLisans';
            app.OnLisansEditField = uieditfield(app.ProgramiButtonGroup, 'text');
            app.LisansEditFieldLabel = uilabel(app.ProgramiButtonGroup);
            app.LisansEditFieldLabel.Text = 'Lisans';
            app.LisansEditField = uieditfield(app.ProgramiButtonGroup, 'text');
            app.YuksekLisansEditFieldLabel = uilabel(app.ProgramiButtonGroup);
            app.YuksekLisansEditFieldLabel.Text = 'YuksekLisans';
            app.YuksekLisansEditField = uieditfield(app.ProgramiButtonGroup, 'text');
            app.egitimsurecButtonGroup = uibuttongroup(app.YonetimPaneliUIFigure);
            app.egitimsurecButtonGroup.Title = 'Normal Eğitim Süresi Içindemi Okuyor Durumu';
            app.OrgunEgitimEditFieldLabel = uilabel(app.egitimsurecButtonGroup);
            app.OrgunEgitimEditFieldLabel.Text = 'Orgun Egitim';
            app.OrgunEgitimEditField = uieditfield(app.egitimsurecButtonGroup, 'text');
            app.UzaktanEgitimEditFieldLabel = uilabel(app.egitimsurecButtonGroup);
            app.UzaktanEgitimEditFieldLabel.Text = 'Uzaktan Egitim';
            app.UzaktanEgitimEditField = uieditfield(app.egitimsurecButtonGroup, 'text');
            app.BursuAlmaButtonGroup = uibuttongroup(app.YonetimPaneliUIFigure);
            app.BursuAlmaButtonGroup.Title = 'Daha Once Burs Alma Durumu';
            app.EvetEditField_2Label = uilabel(app.BursuAlmaButtonGroup);
            app.EvetEditField_2Label.Text = 'Evet';
            app.EvetEditField_2 = uieditfield(app.BursuAlmaButtonGroup, 'text');
            app.HayrEditField_2Label = uilabel(app.BursuAlmaButtonGroup);
            app.HayrEditField_2Label.Text = 'Hayır';
            app.HayrEditField_2 = uieditfield(app.BursuAlmaButtonGroup, 'text');
            app.DisiplinButtonGroup = uibuttongroup(app.YonetimPaneliUIFigure);
            app.DisiplinButtonGroup.Title = 'Disiplin Ceza Olma Durumu';
            app.VarEditFieldLabel = uilabel(app.DisiplinButtonGroup);
            app.VarEditFieldLabel.Text = 'Var';
            app.VarEditField = uieditfield(app.DisiplinButtonGroup, 'text');
            app.YokEditFieldLabel = uilabel(app.DisiplinButtonGroup);
            app.YokEditFieldLabel.Text = 'Yok';
            app.YokEditField = uieditfield(app.DisiplinButtonGroup, 'text');
            app.ogrenciCalismaButtonGroup = uibuttongroup(app.YonetimPaneliUIFigure);
            app.ogrenciCalismaButtonGroup.Title = 'Kisi(ogrenci) calisma durumu';
            app.CalisiyorumEditFieldLabel = uilabel(app.ogrenciCalismaButtonGroup);
            app.CalisiyorumEditFieldLabel.Text = 'Calisiyorum';
            app.CalisiyorumEditField = uieditfield(app.ogrenciCalismaButtonGroup, 'text');
            app.AylkmaaslistecalismaEditFieldLabel = uilabel(app.ogrenciCalismaButtonGroup);
            app.AylkmaaslistecalismaEditFieldLabel.Text = 'Aylık maaslı iste calisma';
            app.AylkmaaslistecalismaEditField = uieditfield(app.ogrenciCalismaButtonGroup, 'text');
            app.GunlukgeilirliistecalismaEditFieldLabel = uilabel(app.ogrenciCalismaButtonGroup);
            app.GunlukgeilirliistecalismaEditFieldLabel.Text = 'Gunluk geilirli  iste calisma';
            app.GunlukgeilirliistecalismaEditField = uieditfield(app.ogrenciCalismaButtonGroup, 'text');
            app.CalismiyorEditField_3Label = uilabel(app.ogrenciCalismaButtonGroup);
            app.CalismiyorEditField_3Label.Text = 'Calismiyor';
            app.CalismiyorEditField_3 = uieditfield(app.ogrenciCalismaButtonGroup, 'text');
            app.ArsaButtonGroup = uibuttongroup(app.YonetimPaneliUIFigure);
            app.ArsaButtonGroup.Title = 'Ailenizin arsa,bag,bahce,gibi gayrimenkulu var Puanı Güncelleme';
            app.EvetEditField_3Label = uilabel(app.ArsaButtonGroup);
            app.EvetEditField_3Label.Text = 'Evet';
            app.EvetEditField_3 = uieditfield(app.ArsaButtonGroup, 'text');
            app.HayrEditField_3Label = uilabel(app.ArsaButtonGroup);
            app.HayrEditField_3Label.Text = 'Hayır';
            app.HayrEditField_3 = uieditfield(app.ArsaButtonGroup, 'text');
            app.isitma = uibuttongroup(app.YonetimPaneliUIFigure);
            app.isitma.Title = 'Oturdugunuz evi ne ile ısıtıyorsunuz Puanı Güncelleme';
            app.KaloriferEditFieldLabel = uilabel(app.isitma);
            app.KaloriferEditFieldLabel.Text = 'Kalorifer';
            app.KaloriferEditField = uieditfield(app.isitma, 'text');
            app.SobaEditFieldLabel = uilabel(app.isitma);
            app.SobaEditFieldLabel.Text = 'Soba';
            app.SobaEditField = uieditfield(app.isitma, 'text');
            app.PUANGUNCELLEButton = uibutton(app.YonetimPaneliUIFigure, 'push');
            app.PUANGUNCELLEButton.ButtonPushedFcn = createCallbackFcn(app, @PUANGUNCELLEButtonPushed, true);
            app.PUANGUNCELLEButton.Icon = fullfile(pathToMLAPP, 'Update.png');
            app.PUANGUNCELLEButton.Text = 'PUAN GUNCELLE';
            app.ContextMenu = uicontextmenu(app.YonetimPaneliUIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.YonetimPaneliUIFigure.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.YonetimPaneliUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = YonetimPaneli

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.YonetimPaneliUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.YonetimPaneliUIFigure)
        end
    end
end